classdef P4_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        qLabel_1                        matlab.ui.control.Label
        qLabel_2                        matlab.ui.control.Label
        qLabel_3                        matlab.ui.control.Label
        qLabel_4                        matlab.ui.control.Label
        qLabel_5                        matlab.ui.control.Label
        qLabel_6                        matlab.ui.control.Label
        CalcularCinemticaDIrectaButton  matlab.ui.control.Button
        ReestablecerButton              matlab.ui.control.Button
        q1SliderLabel                   matlab.ui.control.Label
        q1Slider                        matlab.ui.control.Slider
        q2SliderLabel                   matlab.ui.control.Label
        q2Slider                        matlab.ui.control.Slider
        q3SliderLabel                   matlab.ui.control.Label
        q3Slider                        matlab.ui.control.Slider
        q4SliderLabel                   matlab.ui.control.Label
        q4Slider                        matlab.ui.control.Slider
        q5SliderLabel                   matlab.ui.control.Label
        q5Slider                        matlab.ui.control.Slider
        q6SliderLabel                   matlab.ui.control.Label
        q6Slider                        matlab.ui.control.Slider
        XSpinnerLabel                   matlab.ui.control.Label
        XSpinner                        matlab.ui.control.Spinner
        YSpinnerLabel                   matlab.ui.control.Label
        YSpinner                        matlab.ui.control.Spinner
        ZSpinnerLabel                   matlab.ui.control.Label
        ZSpinner                        matlab.ui.control.Spinner
        RollSpinnerLabel                matlab.ui.control.Label
        RollSpinner                     matlab.ui.control.Spinner
        PitchSpinnerLabel               matlab.ui.control.Label
        PitchSpinner                    matlab.ui.control.Spinner
        YawSpinnerLabel                 matlab.ui.control.Label
        YawSpinner                      matlab.ui.control.Spinner
        CalcularCinemticaInversaButton  matlab.ui.control.Button
        ReestablecerButton_2            matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        robot;
        config;
        f1=figure('Visible','off');
        T0_t;
        xyz;
        rpy;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.robot=loadrobot("abbIrb1600","DataFormat","column");
            app.config=zeros(6,1);
            
            app.T0_t=app.robot.getTransform(app.config,'tool0');
            app.xyz=tform2trvec(app.T0_t);
            %se extraen los angulos roll pitch yaw teniendo en cuenta que el orden
            %´por defecto de la función ZYX
            app.rpy=tform2eul(app.T0_t);
            
            %Se actualizan los spinners:
            app.XSpinner.Value=app.xyz(1);
            app.YSpinner.Value=app.xyz(2);
            app.ZSpinner.Value=app.xyz(3);
            app.RollSpinner.Value=app.rpy(1);
            app.PitchSpinner.Value=app.rpy(2);
            app.YawSpinner.Value=app.rpy(3);
            
            %Se grafica ahora el robot:
            figure(app.f1);
            show(app.robot,app.config,'PreservePlot',false,'Frames',['on']);
            zlim([-1.000 2.000])
            view([120 20])
            set(gcf, 'Visible', 'off');
            image=print('-RGBImage','-r300');
            imshow(image,"Parent",app.UIAxes);
            
        end

        % Button pushed function: CalcularCinemticaDIrectaButton
        function calcularButton(app, event)
            %Primero va a leer el valor de los sliders:
            app.config(1)=app.q1Slider.Value;
            app.config(2)=app.q2Slider.Value;
            app.config(3)=app.q3Slider.Value;
            app.config(4)=app.q4Slider.Value;
            app.config(5)=app.q5Slider.Value;
            app.config(6)=app.q6Slider.Value;
            app.config=deg2rad(app.config);
            
            %Código para graficar el robot:
            ft=figure;
            show(app.robot,app.config,'PreservePlot',false,'Frames',['on']);
            zlim([-1.000 2.000])
            view([120 20])
            set(ft, 'Visible', 'off');
            image=print(ft,'-RGBImage','-r300');
            
            imshow(image,"Parent",app.UIAxes);
            
            %Código apra calcular la posición basado en la MTH:
            app.T0_t=app.robot.getTransform(app.config,'tool0');
            app.xyz=tform2trvec(app.T0_t);
            app.rpy=tform2eul(app.T0_t);
            
            %Se actualizan los spinners:
            app.XSpinner.Value=app.xyz(1);
            app.YSpinner.Value=app.xyz(2);
            app.ZSpinner.Value=app.xyz(3);
            app.RollSpinner.Value=app.rpy(1);
            app.PitchSpinner.Value=app.rpy(2);
            app.YawSpinner.Value=app.rpy(3);
            
        end

        % Value changing function: q1Slider
        function q1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.qLabel_1.Text=num2str(changingValue);
        end

        % Value changing function: q2Slider
        function q2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.qLabel_2.Text=num2str(round(changingValue,1));
        end

        % Value changing function: q3Slider
        function q3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.qLabel_3.Text=num2str(round(changingValue,1));
        end

        % Value changing function: q4Slider
        function q4SliderValueChanging(app, event)
            changingValue = event.Value;
            app.qLabel_4.Text=num2str(round(changingValue,1));
        end

        % Value changing function: q5Slider
        function q5SliderValueChanging(app, event)
            changingValue = event.Value;
            app.qLabel_5.Text=num2str(round(changingValue,1));
        end

        % Value changing function: q6Slider
        function q6SliderValueChanging(app, event)
            changingValue = event.Value;
            app.qLabel_6.Text=num2str(round(changingValue,1));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            close all
        end

        % Button pushed function: CalcularCinemticaInversaButton
        function CalcularCinemticaInversaButtonPushed(app, event)
            app.xyz=[app.XSpinner.Value app.YSpinner.Value app.ZSpinner.Value];
            app.rpy=[app.RollSpinner.Value app.PitchSpinner.Value app.YawSpinner.Value];
            
            %Calcula la cinemática inversa para la posición:
            ik=inverseKinematics('RigidBodyTree',app.robot);
            weights = [0.25 0.25 0.25 1 1 1];
            initialguess = homeConfiguration(app.robot);
            MTHpose=[eul2rotm(app.rpy) app.xyz'; 0 0 0 1];
            
            app.config = ik('tool0',MTHpose,weights,initialguess);
            
            %Se actualizan los valores de los sliders y de los botones de
            %texto:
            
            app.q1Slider.Value=app.config(1);
            app.q2Slider.Value=app.config(2);
            app.q3Slider.Value=app.config(3);
            app.q4Slider.Value=app.config(4);
            app.q5Slider.Value=app.config(5);
            app.q6Slider.Value=app.config(6);
            
            app.qLabel_1.Text=num2str(app.config(1));
            app.qLabel_2.Text=num2str(app.config(2));
            app.qLabel_3.Text=num2str(app.config(3));
            app.qLabel_4.Text=num2str(app.config(4));
            app.qLabel_5.Text=num2str(app.config(5));
            app.qLabel_6.Text=num2str(app.config(6));
            
            %Código para graficar el robot:
            ft=figure;
            show(app.robot,app.config,'PreservePlot',false,'Frames',['on']);
            zlim([-1.000 2.000])
            view([120 20])
            set(ft, 'Visible', 'off');
            image=print(ft,'-RGBImage','-r300');
            
            imshow(image,"Parent",app.UIAxes);
            
            
        end

        % Button pushed function: ReestablecerButton, 
        % ReestablecerButton_2
        function ReestablecerButtonPushed(app, event)
            %Se devuelve el robot a su configuración de home
            app.config = homeConfiguration(app.robot);
            
            %Se actualizan los valores de los sliders y de los botones de
            %texto:
            
            app.q1Slider.Value=app.config(1);
            app.q2Slider.Value=app.config(2);
            app.q3Slider.Value=app.config(3);
            app.q4Slider.Value=app.config(4);
            app.q5Slider.Value=app.config(5);
            app.q6Slider.Value=app.config(6);
            
            app.qLabel_1.Text=num2str(app.config(1));
            app.qLabel_2.Text=num2str(app.config(2));
            app.qLabel_3.Text=num2str(app.config(3));
            app.qLabel_4.Text=num2str(app.config(4));
            app.qLabel_5.Text=num2str(app.config(5));
            app.qLabel_6.Text=num2str(app.config(6));
            
            %Código apra calcular la posición basado en la MTH:
            app.T0_t=app.robot.getTransform(app.config,'tool0');
            app.xyz=tform2trvec(app.T0_t);
            app.rpy=tform2eul(app.T0_t);
            
            %Se actualizan los spinners:
            app.XSpinner.Value=app.xyz(1);
            app.YSpinner.Value=app.xyz(2);
            app.ZSpinner.Value=app.xyz(3);
            app.RollSpinner.Value=app.rpy(1);
            app.PitchSpinner.Value=app.rpy(2);
            app.YawSpinner.Value=app.rpy(3);
            
            %Código para graficar el robot:
            ft=figure;
            show(app.robot,app.config,'PreservePlot',false,'Frames',['on']);
            zlim([-1.000 2.000])
            view([120 20])
            set(ft, 'Visible', 'off');
            image=print(ft,'-RGBImage','-r300');
            
            imshow(image,"Parent",app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.qLabel_1 = uilabel(app.GridLayout2);
            app.qLabel_1.Text = '0';
            app.qLabel_2 = uilabel(app.GridLayout2);
            app.qLabel_2.Text = '0';
            app.qLabel_3 = uilabel(app.GridLayout2);
            app.qLabel_3.Text = '0';
            app.qLabel_4 = uilabel(app.GridLayout2);
            app.qLabel_4.Text = '0';
            app.qLabel_5 = uilabel(app.GridLayout2);
            app.qLabel_5.Text = '0';
            app.qLabel_6 = uilabel(app.GridLayout2);
            app.qLabel_6.Text = '0';
            app.CalcularCinemticaDIrectaButton = uibutton(app.GridLayout2, 'push');
            app.CalcularCinemticaDIrectaButton.ButtonPushedFcn = createCallbackFcn(app, @calcularButton, true);
            app.CalcularCinemticaDIrectaButton.Text = 'Calcular Cinemática DIrecta';
            app.ReestablecerButton = uibutton(app.GridLayout2, 'push');
            app.ReestablecerButton.ButtonPushedFcn = createCallbackFcn(app, @ReestablecerButtonPushed, true);
            app.ReestablecerButton.Text = 'Reestablecer';
            app.q1SliderLabel = uilabel(app.GridLayout2);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.GridLayout2);
            app.q1Slider.Limits = [-180 180];
            app.q1Slider.MajorTicks = [-180 -160 -140 -120 -100 -80 -60 -40 -20 0 20 40 60 80 100 120 140 160 180];
            app.q1Slider.MajorTickLabels = {'-180', '', '', '-120', '', '', '-60', '', '', '0', '', '', '60', '', '', '120', '', '', '180'};
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @q1SliderValueChanging, true);
            app.q2SliderLabel = uilabel(app.GridLayout2);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.GridLayout2);
            app.q2Slider.Limits = [-63 110];
            app.q2Slider.MajorTicks = [-63 -40 -10 20 50 80 110];
            app.q2Slider.ValueChangingFcn = createCallbackFcn(app, @q2SliderValueChanging, true);
            app.q3SliderLabel = uilabel(app.GridLayout2);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.GridLayout2);
            app.q3Slider.Limits = [-235 55];
            app.q3Slider.ValueChangingFcn = createCallbackFcn(app, @q3SliderValueChanging, true);
            app.q4SliderLabel = uilabel(app.GridLayout2);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.GridLayout2);
            app.q4Slider.Limits = [-200 200];
            app.q4Slider.ValueChangingFcn = createCallbackFcn(app, @q4SliderValueChanging, true);
            app.q5SliderLabel = uilabel(app.GridLayout2);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.GridLayout2);
            app.q5Slider.Limits = [-115 115];
            app.q5Slider.ValueChangingFcn = createCallbackFcn(app, @q5SliderValueChanging, true);
            app.q6SliderLabel = uilabel(app.GridLayout2);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.GridLayout2);
            app.q6Slider.Limits = [-400 400];
            app.q6Slider.ValueChangingFcn = createCallbackFcn(app, @q6SliderValueChanging, true);
            app.XSpinnerLabel = uilabel(app.GridLayout2);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.GridLayout2);
            app.XSpinner.Step = 0.1;
            app.XSpinner.Limits = [-2 2];
            app.YSpinnerLabel = uilabel(app.GridLayout2);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.GridLayout2);
            app.YSpinner.Step = 0.1;
            app.YSpinner.Limits = [-2 2];
            app.ZSpinnerLabel = uilabel(app.GridLayout2);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.GridLayout2);
            app.ZSpinner.Step = 0.1;
            app.ZSpinner.Limits = [-1 2];
            app.RollSpinnerLabel = uilabel(app.GridLayout2);
            app.RollSpinnerLabel.Text = 'Roll';
            app.RollSpinner = uispinner(app.GridLayout2);
            app.RollSpinner.Step = 0.0314159265358979;
            app.RollSpinner.Limits = [-6.28318530717959 6.28318530717959];
            app.PitchSpinnerLabel = uilabel(app.GridLayout2);
            app.PitchSpinnerLabel.Text = 'Pitch';
            app.PitchSpinner = uispinner(app.GridLayout2);
            app.PitchSpinner.Step = 0.0314159265358979;
            app.PitchSpinner.Limits = [-6.28318530717959 6.28318530717959];
            app.YawSpinnerLabel = uilabel(app.GridLayout2);
            app.YawSpinnerLabel.Text = 'Yaw';
            app.YawSpinner = uispinner(app.GridLayout2);
            app.YawSpinner.Step = 0.0314159265358979;
            app.YawSpinner.Limits = [-6.28318530717959 6.28318530717959];
            app.CalcularCinemticaInversaButton = uibutton(app.GridLayout2, 'push');
            app.CalcularCinemticaInversaButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularCinemticaInversaButtonPushed, true);
            app.CalcularCinemticaInversaButton.Text = 'Calcular Cinemática Inversa';
            app.ReestablecerButton_2 = uibutton(app.GridLayout2, 'push');
            app.ReestablecerButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReestablecerButtonPushed, true);
            app.ReestablecerButton_2.Text = 'Reestablecer';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Gráfica del Robot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P4_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end